classdef orbit_term_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_2                      matlab.ui.control.Label
        LonEditFieldLabel_5          matlab.ui.control.Label
        ElevationmaskEditField       matlab.ui.control.EditField
        ElevationmaskEditFieldLabel  matlab.ui.control.Label
        LonEditFieldLabel_4          matlab.ui.control.Label
        LonEditFieldLabel_3          matlab.ui.control.Label
        LonEditFieldLabel_2          matlab.ui.control.Label
        HeightEditField              matlab.ui.control.EditField
        HeightEditFieldLabel         matlab.ui.control.Label
        LatEditField                 matlab.ui.control.EditField
        LatEditFieldLabel            matlab.ui.control.Label
        LonEditField                 matlab.ui.control.EditField
        LonEditFieldLabel            matlab.ui.control.Label
        System                       matlab.ui.control.DropDown
        SystemDropDownLabel          matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
        EpochEditField               matlab.ui.control.EditField
        EpochEditFieldLabel          matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        Groundtrack                  matlab.ui.control.UIAxes
        Skyplot                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.mat');
            Index0 = strfind(FileName,'.');
            eval(['load ' FileName(1:Index0-1)]);
        end

        % Clicked callback: System
        function SystemClicked(app, event)
            ground_lon = app.LonEditField.Value;
            ground_lat = app.LatEditField.Value;
            ground_h = app.HeightEditField.Value;


            GNSS = app.System.Value;
            switch GNSS
                case 'All'
                    app.EpochEditField.Value = 'All';
                case 'GPS'
                    app.EpochEditField.Value = 'GPS';

                    a = nav.GPS.a;
                    e = nav.GPS.e;
                    i = nav.GPS.i;
                    omega = nav.GPS.omega;
                    M0 = nav.GPS.M0;
                    RAAN = nav.GPS.OMEGA;
                    toc = nav.GPS.toc;

                    del = 1;
                    E0 = M0;
                    while del <= e-3
                        E = M0 + e*sin(E0);
                        del = abs(E - E0);
                        E0 = E;
                    end

                    Time = datetime(toc);
                    ini_Time = juliandate(Time);
                    final_Time = juliandate(datetime([2023,4,30,0,0,0]));

                    for


                case 'QZSS'
                    app.EpochEditField.Value = 'QZSS';
                case 'BDS'
                    app.EpochEditField.Value = 'BDS';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Skyplot = uiaxes(app.UIFigure);
            title(app.Skyplot, 'Sky Plot')
            xlabel(app.Skyplot, 'X')
            ylabel(app.Skyplot, 'Y')
            zlabel(app.Skyplot, 'Z')
            app.Groundtrack = uiaxes(app.UIFigure);
            title(app.Groundtrack, 'Ground Track')
            xlabel(app.Groundtrack, 'X')
            ylabel(app.Groundtrack, 'Y')
            zlabel(app.Groundtrack, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.EpochEditFieldLabel = uilabel(app.UIFigure);
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2023-04-29';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.MinorTicks = [];
            app.SystemDropDownLabel = uilabel(app.UIFigure);
            app.SystemDropDownLabel.Text = 'System';
            app.System = uidropdown(app.UIFigure);
            app.System.Items = {'All', 'GPS', 'QZSS', 'BDS'};
            app.System.ClickedFcn = createCallbackFcn(app, @SystemClicked, true);
            app.System.Value = 'All';
            app.LonEditFieldLabel = uilabel(app.UIFigure);
            app.LonEditFieldLabel.Text = 'Lon';
            app.LonEditField = uieditfield(app.UIFigure, 'text');
            app.LatEditFieldLabel = uilabel(app.UIFigure);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField = uieditfield(app.UIFigure, 'text');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'text');
            app.LonEditFieldLabel_2 = uilabel(app.UIFigure);
            app.LonEditFieldLabel_2.Text = 'deg';
            app.LonEditFieldLabel_3 = uilabel(app.UIFigure);
            app.LonEditFieldLabel_3.Text = 'deg';
            app.LonEditFieldLabel_4 = uilabel(app.UIFigure);
            app.LonEditFieldLabel_4.Text = 'km';
            app.ElevationmaskEditFieldLabel = uilabel(app.UIFigure);
            app.ElevationmaskEditFieldLabel.Text = 'Elevation mask';
            app.ElevationmaskEditField = uieditfield(app.UIFigure, 'text');
            app.LonEditFieldLabel_5 = uilabel(app.UIFigure);
            app.LonEditFieldLabel_5.Text = 'deg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2023-04-30';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orbit_term_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end